classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectImageButton          matlab.ui.control.Button
        TargetImageEditFieldLabel  matlab.ui.control.Label
        TargetImageEditField       matlab.ui.control.EditField
        TileSizeEditFieldLabel     matlab.ui.control.Label
        TileSizeEditField          matlab.ui.control.NumericEditField
        RunButton                  matlab.ui.control.Button
        SelectButton               matlab.ui.control.Button
        OutputImageEditFieldLabel  matlab.ui.control.Label
        OutputImageEditField       matlab.ui.control.EditField
    end


    methods (Access = private)
    
        function cells = tile(app, image, tile_size)
            [height, width, depth] = size(image);

            % The number of whole row & column blocks within the image
            rows = floor(height / tile_size);
            columns = floor(width / tile_size);
            
            % the width of each row & column respectively
            rows = [tile_size * ones(1, rows), rem(height, tile_size)];
            columns = [tile_size * ones(1, columns), rem(width, tile_size)];
            
            % convert to cells, last row & column will be uncomplete blocks
            % they are removed to crop the iamge
            cells = mat2cell(image, rows, columns, depth);
            cells = cells(1:end-1,1:end-1);
        end
        
        function image = stitch(app, cells)
            for i=1:length(cells) 
                for j=1:length(cells(i))
                    image = cells{i,j};
                end
            end
        end
    end


    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
           [FileName,PathName] = uigetfile('*.jpg','Select Target Image');
           app.TargetImageEditField.Value = strcat(PathName, FileName);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
           [FileName,PathName] = uiputfile('output','Save as..');
           app.OutputImageEditField.Value = strcat(PathName, FileName);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            image = imread(app.TargetImageEditField.Value);
            tile_size = app.TileSizeEditField.Value;
            cells = tile(app, image, tile_size);
            imshow(cells{1,1});
            stitched = stitch(app, cells);
            imshow(stitched);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.TargetImageEditFieldLabel = uilabel(app.UIFigure);
            app.TargetImageEditFieldLabel.Text = 'Target Image';
            app.TargetImageEditField = uieditfield(app.UIFigure, 'text');
            app.TileSizeEditFieldLabel = uilabel(app.UIFigure);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TileSizeEditField.Limits = [0 1024];
            app.TileSizeEditField.ValueDisplayFormat = '%.0f';
            app.TileSizeEditField.Value = 64;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.OutputImageEditFieldLabel = uilabel(app.UIFigure);
            app.OutputImageEditFieldLabel.Text = 'Output Image';
            app.OutputImageEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end